classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        F3AAcquisitionSystemRUIFigure  matlab.ui.Figure
        GeneralMenu                    matlab.ui.container.Menu
        ConnectdeviceMenu              matlab.ui.container.Menu
        ConfiguredeviceMenu            matlab.ui.container.Menu
        CalibratesensorsMenu           matlab.ui.container.Menu
        ErasedevicememoryMenu          matlab.ui.container.Menu
        LoadflightdataMenu             matlab.ui.container.Menu
        LoadfromdevicememoryMenu       matlab.ui.container.Menu
        LoadfromcomputerfileMenu       matlab.ui.container.Menu
        SaveflightdataMenu             matlab.ui.container.Menu
        FlightTrackMenu                matlab.ui.container.Menu
        SelectflighttrackMenu          matlab.ui.container.Menu
        AddnewflighttrackMenu          matlab.ui.container.Menu
        ManageflighttracksMenu         matlab.ui.container.Menu
        ManeuverscheduleMenu           matlab.ui.container.Menu
        AddnewscheduleMenu             matlab.ui.container.Menu
        LoadscheduleMenu               matlab.ui.container.Menu
        ManageschedulesMenu            matlab.ui.container.Menu
        AircraftmodelMenu              matlab.ui.container.Menu
        AddnewmodelMenu                matlab.ui.container.Menu
        ManagemodelsMenu               matlab.ui.container.Menu
        FlightViewerMenu               matlab.ui.container.Menu
        ViewsMenu                      matlab.ui.container.Menu
        AllViewsMenu                   matlab.ui.container.Menu
        FrontalviewonlyMenu            matlab.ui.container.Menu
        LateralviewonlyMenu            matlab.ui.container.Menu
        TopviewonlyMenu                matlab.ui.container.Menu
        DviewonlyMenu                  matlab.ui.container.Menu
        MainaircraftytrajectoryMenu    matlab.ui.container.Menu
        NotrajectorydisplayMenu        matlab.ui.container.Menu
        ShowaircrafttrailMenu          matlab.ui.container.Menu
        ShowpasttrajectoryMenu         matlab.ui.container.Menu
        ShowfulltrajectoryMenu         matlab.ui.container.Menu
        ManageloadedflightsMenu        matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        ConnectSwitch                  matlab.ui.container.toolbar.ToggleTool
        CalibrateSensorsButton         matlab.ui.container.toolbar.PushTool
        EraseDevMemoryButton           matlab.ui.container.toolbar.PushTool
        LoadDataDeviceButton           matlab.ui.container.toolbar.PushTool
        LoadDataComputerButton         matlab.ui.container.toolbar.PushTool
        LoadManeuverSchButton          matlab.ui.container.toolbar.PushTool
        SaveFlightButton               matlab.ui.container.toolbar.PushTool
        ManageLoadedFlightsButton      matlab.ui.container.toolbar.PushTool
        FrontalViewButton              matlab.ui.container.toolbar.PushTool
        LateralViewButton              matlab.ui.container.toolbar.PushTool
        TopViewButton                  matlab.ui.container.toolbar.PushTool
        DViewButton                    matlab.ui.container.toolbar.PushTool
        AllViewButton                  matlab.ui.container.toolbar.PushTool
        GlobalGridLayout               matlab.ui.container.GridLayout
        PlaybackControlsPanel          matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        RepeatButton                   matlab.ui.control.StateButton
        PlaybackSlider                 matlab.ui.control.Slider
        FlightInstantEditField         matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        PlaybackDropDownLabel          matlab.ui.control.Label
        PlaybackDropDown               matlab.ui.control.DropDown
        NextFrameButton                matlab.ui.control.Button
        StopPlaybackButton             matlab.ui.control.Button
        PlayPlaybackButton             matlab.ui.control.Button
        PrevFrameButton                matlab.ui.control.Button
        GridLayout2                    matlab.ui.container.GridLayout
        TrajectoryPanel                matlab.ui.container.Panel
        ViewersGridLayout              matlab.ui.container.GridLayout
        TridViewUIAxes                 matlab.ui.control.UIAxes
        TopViewUIAxes                  matlab.ui.control.UIAxes
        LateralViewUIAxes              matlab.ui.control.UIAxes
        FrontalViewUIAxes              matlab.ui.control.UIAxes
        GridLayout8                    matlab.ui.container.GridLayout
        DatagraphsPanel                matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        Graph1Tab                      matlab.ui.container.Tab
        Graph2Tab                      matlab.ui.container.Tab
        Label_6                        matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        DevicePropertiesPanel          matlab.ui.container.Panel
        GridLayout9                    matlab.ui.container.GridLayout
        DeviceInfoTextArea             matlab.ui.control.TextArea
        Label_5                        matlab.ui.control.Label
    end

    % Global variables
    properties (Access = public)
        StateFSM; EventFSMQueue % queue of events
        HC06 = struct('Obj','','Cnt',0); %Cnt counts number of 512 bytes packets already transmited
        %RawDeviceInfo = zeros(5,1,'uint8');
        DeviceInfo = struct('Name','','DataAvailable',false,'SectorCount4K',[],'PacketsCount',[],'nSamples',[]); %PacketsCount is the total amount of 512 bytes packets to be transmited
        RawSensorData
        SensorData = struct('nElements',[],'altData',[],'accelData',[],'gyroData',[],'magnetoData',[],'gpsData',[]);
        GraphsLink
        Flights % matrix that stores flight objects: contain all flight information
        nFlights % number of loaded flight objects
        w % waitbar
    end
    
    % Auxiliary apps
    properties (Access = private)
        FlightsManagerApp
    end
    
    % HC06 commands constants
    properties (Constant)
        BLE_CMD_GetDevInfo = 'HC06-0'
        BLE_CMD_SetDevInfo = 'HC06-1'
        BLE_CMD_CalibSensors = 'HC06-2'
        BLE_CMD_EraseMemory = 'HC06-3'
        BLE_CMD_ReadMemory = 'HC06-4'
    end
    
    % Player properties
    properties (Access = public)
        PlayerTimer
        PlayerFrame
    end
    
    % FSM function
    methods (Access = private)
        
        % Process events and updates FSM state
        function FSM(app)
            eventFSM = processEvent(app.EventFSMQueue);
            switch app.StateFSM
                case 'Playing'
                    switch eventFSM
                        case 'PlayPause'
                            pausePlayer(app);
                            app.StateFSM = 'Paused';
                        otherwise
                    end
                case 'Paused'
                    switch eventFSM
                        case 'PlayPause'
                            playPlayer(app);
                            app.StateFSM = 'Playing';
                        otherwise
                    end
                case 'Initial'
                    switch eventFSM
                        case 'ConnectButtonPressed'
                            if strcmp(app.ConnectdeviceMenu.Text,'Connect device') %if the bluetooth was disconnected
                                app.w = waitbar(0.1, 'Searching for devices...');
                                try % try to connect to bluetooth device
                                    app.HC06.Obj = bluetooth("HC-06");
                                    waitbar(0.8, app.w,'Device connected');
                                    app.ConnectSwitch.State = 'on';
                                    app.ConnectSwitch.Icon = 'Icons\Disconnect.png'; 
                                    app.ConnectdeviceMenu.Text = 'Disconnect device';
                                    app.ErasedevicememoryMenu.Enable = 'on';
                                    app.EraseDevMemoryButton.Enable = 'on';
                                    configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
                                    app.StateFSM = 'ReceivingHC06_DevData';
                                    waitbar(1, app.w,'Connection succesful');
                                    pause(3);
                                    close(app.w);
                                    write(app.HC06.Obj,app.BLE_CMD_GetDevInfo);
                                catch ME
                                    switch ME.identifier
                                        case {'MATLAB:bluetooth:bluetooth:invalidIdentifierValue'}
                                            text = 'First pair the device with computer bluetooth';     
                                        case {'MATLAB:bluetooth:bluetoothlist:winBluetoothNotPoweredOn'}
                                            text = 'Computer bluetooth is OFF. Turn it ON!';
                                        case {'MATLAB:bluetooth:bluetooth:failedConnect'}
                                            text = 'Device is OFF. Turn it ON!';
                                    end
                                    app.ConnectSwitch.State = 'off';
                                    waitbar(1, app.w,text);
                                    pause(3);
                                    close(app.w);   
                                end
                            else % if the BLE was connected
                                app.HC06.Obj = '';
                                app.ConnectSwitch.State = 'off';
                                app.ConnectSwitch.Icon = 'Icons\Connect.png'; 
                                app.ConnectdeviceMenu.Text = 'Connect device';    
                                app.CalibratesensorsMenu.Enable = 'off';
                                app.CalibrateSensorsButton.Enable = 'off';
                                app.ErasedevicememoryMenu.Enable = 'off';
                                app.EraseDevMemoryButton.Enable = 'off'; 
                                clearDeviceInfoBox(app);
                            end
                        case 'LoadDeviceDataButtonPressed'
                            app.HC06.Cnt = 0;
                            %configureCallback(app.HC06.Obj,"byte",512,@(obj,event)app.readHC06Data(obj));
                            configureCallback(app.HC06.Obj,"byte",4096,@(obj,event)app.readHC06Data(obj));
                            app.w = waitbar(0, 'Reading sensors data...');
                            app.StateFSM = 'ReceivingHC06_SensorData';
                            write(app.HC06.Obj,app.BLE_CMD_ReadMemory);
                        case 'CalibrateButtonPressed'
                            write(app.HC06.Obj,app.BLE_CMD_CalibSensors);
                        case 'EraseButtonPressed'
                            write(app.HC06.Obj,app.BLE_CMD_EraseMemory);
                            app.w = waitbar(0.1,'Erasing device memory...');
                            for i = 1:30
                                pause(1); %takes 30 seconds as maximum
                                waitbar(i/30,app.w,'Erasing device memory...');
                            end
                            close(app.w); 
                            configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
                            app.StateFSM = 'ReceivingHC06_DevData';
                            write(app.HC06.Obj,app.BLE_CMD_GetDevInfo);
                        case 'PlayPause'
                            playPlayer(app);
                            app.StateFSM = 'Playing';
                        case 'LoadComputerData'
                            %openFlightsManagerApp(app);
                            % test line
                            loadFlightDataComputer(app); % esta linea hay que quitarla luego
                            app.StateFSM = 'Paused';
                        otherwise                        
                    end
                case 'ReceivingHC06_DevData'
                    switch eventFSM
                        case 'HC06_Rx_Finished'
                            app.StateFSM = 'Initial';
                    end
                case 'ReceivingHC06_SensorData'
                    switch eventFSM
                        case 'HC06_Rx_Finished'
                            app.StateFSM = 'Initial';
                    end                       
            end
        end

    end
    
    % Auxiliary apps open related methods
    methods (Access = private)
        
        function openFlightsManagerApp(app)
            app.LoadDataComputerButton.Enable = 'off';
            app.FlightsManagerApp = FlightsManager(app);
        end
        
    end
    
    % Data related functions
    methods (Access = public)
        
        % Load default app options
        function loadDefaultUserOptions(app) %#ok<MANU> 
            
        end 
        
        % Stores flight data from device
        function readHC06Data(app,src)
            toc
            tic
            switch(app.StateFSM)
                case 'ReceivingHC06_DevData' 
                    RawDeviceInfo = read(src,src.BytesAvailableFcnCount);
                    app.DeviceInfo.Name = char(RawDeviceInfo(4:53));
                    if RawDeviceInfo(1) %if there is no data in the device memory
                        app.DeviceInfo.DataAvailable = false;
                        app.LoadfromdevicememoryMenu.Enable = 'off';
                        app.LoadDataDeviceButton.Enable = 'off';
                        app.DeviceInfo.SectorCount4K = 0;
                        app.RawSensorData = 0;
                        app.DeviceInfo.nSamples = 0;
                    else
                        app.DeviceInfo.DataAvailable = true;
                        app.LoadfromdevicememoryMenu.Enable = 'on';
                        app.LoadDataDeviceButton.Enable = 'on';
                        app.DeviceInfo.SectorCount4K = uint16(typecast(cast(RawDeviceInfo(2:3),'uint8'),'uint16'));
                        app.DeviceInfo.PacketsCount = app.DeviceInfo.SectorCount4K*8; % each sector has 8 packets of 512 bytes
                        app.RawSensorData = zeros(4096*app.DeviceInfo.SectorCount4K,1,'uint8');
                        app.DeviceInfo.nSamples = uint32(typecast(cast(RawDeviceInfo(54:57),'uint8'),'uint32'));
                    end
                    updateDeviceInfoBox(app);
                    addEvent(app.EventFSMQueue,'HC06_Rx_Finished');
                    FSM(app);                            
                case 'ReceivingHC06_SensorData'
                    app.RawSensorData((app.HC06.Cnt*4096+1):((app.HC06.Cnt+1)*4096)) = read(src,src.BytesAvailableFcnCount);
                    app.HC06.Cnt = app.HC06.Cnt + 1; 
                    if (app.HC06.Cnt == app.DeviceInfo.SectorCount4K)
                        close(app.w)
                        configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
                        addEvent(app.EventFSMQueue,'HC06_Rx_Finished');
                        FSM(app);                         
                    else
                        waitbar(double(app.HC06.Cnt)/double(app.DeviceInfo.SectorCount4K), app.w,'Reading sensors data...');
                    end
%                     app.RawSensorData((app.HC06.Cnt*512+1):((app.HC06.Cnt+1)*512)) = read(src,src.BytesAvailableFcnCount);
%                     app.HC06.Cnt = app.HC06.Cnt + 1; 
%                     if (app.HC06.Cnt == app.DeviceInfo.PacketsCount)
%                         close(app.w)
%                         configureCallback(app.HC06.Obj,"byte",57,@(obj,event)app.readHC06Data(obj));
%                         addEvent(app.EventFSMQueue,'HC06_Rx_Finished');
%                         FSM(app);                         
%                     else
%                         waitbar(double(app.HC06.Cnt)/double(app.DeviceInfo.PacketsCount), app.w,'Reading sensors data...');
%                     end
            end
        end
        
        % Sensor data parsing funciton
%         function parseRawSensorData(app)
%             idx = 1;
%             maxCount = app.DeviceInfo.SectorCount4K*4096;
%             while ((idx+132) < maxCount)
%                 
%                 app.SensorData.altData
%                 app.SensorData.accelData
%                 app.SensorData.gyroData
%                 app.SensorData.magnetoData
%                 app.SensorData.gpsData
%                 idx = idx + 132;
%             end
%             app.SensorData.nElements
%         end
        
        % Loads raw sensor flight data from mat file. Function called by Flights manager app
        function loadFlightDataComputer(app)
            app.nFlights = app.nFlights + 1;
            app.Flights(app.nFlights) = flight; % add new flight object to the matrix
            %parseFlightFileData(app.flights(app.nFlights)); %stores
            %the file info in the flight object. cargaríamos el objeto
            %flightData guardado en un mat file
            %% test lines
            app.Flights(app.nFlights).Aircraft = aircraft;
            t = 0:0.05:18;
            px = linspace(-200,200,numel(t));
            py = ones(1,numel(t))*150;
            pz = ones(1,numel(t))*100;
            app.Flights(app.nFlights).GraphData.Time = t;
            app.Flights(app.nFlights).GraphData.Position = [px' py' pz'];
            %app.Flights(app.nFlights).GraphData.Attitude = [0 0 0; 10 10 5; 20 20 7; 4 1 10;6 8 9];
            %%% test lines
            showPlayer(app); % shows player bar
            initPlayer(app); % initializes player
        end
        
        % Calculates longer flight duration of the stored flights
        function maxDuration = longerFlightDuration(app)
            maxDuration = 0;
            for i=1:app.nFlights
                thisDuration = app.Flights(i).GraphData.Duration;
                if thisDuration > maxDuration
                    maxDuration = thisDuration;
                end
            end
        end
        
    end
    
    % Player functions
    methods (Access = private)
        
        % Player timer callback
        function playerTimerISR(app,~,~)
            app.PlayerFrame = app.PlayerFrame + 1;
            moveToFramePlayer(app,app.PlayerFrame);
        end
        
        function playPlayer(app)
            app.PlayPlaybackButton.Icon = 'Pause.png';
            start(app.PlayerTimer);
        end
        
        function pausePlayer(app)
            stop(app.PlayerTimer);
            app.PlayPlaybackButton.Icon = 'Play.png';
            
        end
        
        function stopPlayer(app) %#ok<MANU> 
            
        end
        
        function nextFramePlayer(app) %#ok<MANU> 
            
        end
        
        function prevFramePlayer(app) %#ok<MANU> 
            
        end
        
        function changePlaybackSpeedPlayer(app)
            % parar el timer si esta en funcionamiento
            % cambiar la graphData de los flights (ajustar escalas para que no vaya a saltos)
            % cambiar la temporización del timer
            %app.PlayerTimer.Period = app.Flights(1).GraphData.DeltaT*app.PlaybackDropDown.Value;
            app.PlayerTimer.Period = 0.2;
            % ver cuantas veces tiene que rular el timer
            %app.PlayerTimer.TasksToExecute = app.Flights(1).GraphData.nElements - app.PlayerFrame;
            app.PlayerTimer.TasksToExecute = 360;
            % reiniciar el timer si estaba en marcha
        end
        
        function moveToFramePlayer(app,frame)
            % aqui habría que hacer un for y mover todos los vuelos
            % seleccionados
            flightNumber = 1;
            moveAircraft(app.Flights(flightNumber).Aircraft,app.Flights(flightNumber).GraphData.Position(frame,:));
            updateAircraftPosViewers(app,1);
        end
        
        function initPlayer(app)
            % call adapt frame rate method of the flight.graphdata object to the selected rate on the player
            moveToFramePlayer(app,1);
            app.PlaybackSlider.Limits = [0, longerFlightDuration(app)];
            app.PlaybackSlider.Value = 0;
            app.PlayerFrame = 1;
            changePlaybackSpeedPlayer(app)
        end
        
        function hidePlayer(app)
            app.GlobalGridLayout.RowHeight{2} = 0; % Hides player bar
        end
        
        function showPlayer(app)
            app.GlobalGridLayout.RowHeight{2} = 45; % Displays player bar
        end
    end
    
    % Graphics & interface related functions
    methods (Access = private)
        
        % Initialize interface objects status and plots (user default)
        function initAppInterface(app)
            hidePlayer(app); %hides bar player
            % Plot ref points and lines on the 3d viewer
            hold(app.TridViewUIAxes,'on');
            patch(app.TridViewUIAxes,'XData',[-350,-350,350,350],'YData',[-10,150,150,-10],'ZData',[0,0,0,0],'FaceColor',[0.6510,0.6510,0.6510]); % track
            patch(app.TridViewUIAxes,'XData',[-350,-350,350,350],'YData',[150,200,200,150],'ZData',[0,0,0,0],'FaceColor',[0.4667,0.6745,0.1882]); % track grass
            plot3(app.TridViewUIAxes,[-260,260],[0,0],[0,0],'LineStyle',"--","Color",[1,0.4118,0.1608]); %pilot line
            plot3(app.TridViewUIAxes,[0,-260],[0,150],[0,0],"Color",'w'); %left floor line
            plot3(app.TridViewUIAxes,[0,260],[0,150],[0,0],"Color",'w'); %right floor line
            plot3(app.TridViewUIAxes,[0,0],[0,150],[0,0],"Color",'w'); %mid line
            plot3(app.TridViewUIAxes,[-260,260],[-7,-7],[0,0],'LineStyle',"--","Color",[0.7176,0.2745,1]); %judges line
            plot3(app.TridViewUIAxes,[-260,-260,260,260,-260],[150,150,150,150,150],[0,270,270,0,0],'LineStyle',"--","Color",'r'); %window
            plot3(app.TridViewUIAxes,[0,0],[150,150],[0,270],'LineStyle',"--","Color",'r'); %mid line
            plot3(app.TridViewUIAxes,0,0,0,'Marker',"o",'MarkerEdgeColor','k',"MarkerFaceColor",'k'); %pilot point
            plot3(app.TridViewUIAxes,[],[],[]); % dummy trajectory line children
            dummyS.Faces = [1,2,3];
            dummyS.Vertices = [0,0,0;0,0,0;0,0,0];
            patch(app.TridViewUIAxes,dummyS,'Facecolor','b','EdgeColor','none'); % dummy aircraft patch children
            hold(app.TridViewUIAxes,'off');
            copyobj(app.TridViewUIAxes.Children,app.FrontalViewUIAxes);
            copyobj(app.TridViewUIAxes.Children,app.LateralViewUIAxes);
            copyobj(app.TridViewUIAxes.Children,app.TopViewUIAxes);
            % Link some axis properties
            app.GraphsLink = linkprop([app.TridViewUIAxes,app.FrontalViewUIAxes,app.LateralViewUIAxes,app.TopViewUIAxes],{'XLim','YLim','ZLim','DataAspectRatio'});
            % Set axis limits
            app.TridViewUIAxes.DataAspectRatio = [1 1 1];
            app.TridViewUIAxes.XLim = [-350,350];
            app.TridViewUIAxes.YLim = [-10,200];
            app.TridViewUIAxes.ZLim = [0,300];
            app.FrontalViewUIAxes.DataAspectRatio = [1 1 1];
            app.FrontalViewUIAxes.XLim = [-350,350];
            app.FrontalViewUIAxes.YLim = [-10,200];
            app.FrontalViewUIAxes.ZLim = [0,300];
            app.LateralViewUIAxes.DataAspectRatio = [1 1 1];
            app.LateralViewUIAxes.XLim = [-350,350];
            app.LateralViewUIAxes.YLim = [-10,200];
            app.LateralViewUIAxes.ZLim = [0,300];
            app.TopViewUIAxes.DataAspectRatio = [1 1 1];
            app.TopViewUIAxes.XLim = [-350,350];
            app.TopViewUIAxes.YLim = [-10,200];
            app.TopViewUIAxes.ZLim = [0,300];
            % Set views
            view(app.TridViewUIAxes,30,20);
            view(app.FrontalViewUIAxes,0,0);
            view(app.LateralViewUIAxes,270,0);
            view(app.TopViewUIAxes,0,90);
            % Configure toolbar and zoom options
%             app.FrontalViewUIAxes.Interactions = regionZoomInteraction;
%             app.LateralViewUIAxes.Interactions = regionZoomInteraction;
%             app.TopViewUIAxes.Interactions = regionZoomInteraction;
            setViewersMode(app,'3DView'); % Set viewer default mode
        end

        % Set viewers mode
        function setViewersMode(app,mode)
            switch mode
                case 'AllViews'
                    app.ViewersGridLayout.RowHeight = {'1x','1x'};
                    app.ViewersGridLayout.ColumnWidth = {'1x','1x'};
                    app.AllViewsMenu.Checked = 'on';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'off';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','on');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','on');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','on');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','on');
                case 'FrontalView'
                    app.ViewersGridLayout.RowHeight = {'1x',0};
                    app.ViewersGridLayout.ColumnWidth = {'1x',0};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'on';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'off';      
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','off');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','off');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','off');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','on');                  
                case 'LateralView'
                    app.ViewersGridLayout.RowHeight = {'1x',0};
                    app.ViewersGridLayout.ColumnWidth = {0,'1x'};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'on';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'off';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','off');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','on');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','off');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','off');                   
                case 'TopView'
                    app.ViewersGridLayout.RowHeight = {0,'1x'};
                    app.ViewersGridLayout.ColumnWidth = {'1x',0};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'on';
                    app.DviewonlyMenu.Checked = 'off';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','off');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','off');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','on');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','off');                   
                case '3DView'
                    app.ViewersGridLayout.RowHeight = {0,'1x'};
                    app.ViewersGridLayout.ColumnWidth = {0,'1x'};
                    app.AllViewsMenu.Checked = 'off';
                    app.FrontalviewonlyMenu.Checked = 'off';
                    app.LateralviewonlyMenu.Checked = 'off';
                    app.TopviewonlyMenu.Checked = 'off';
                    app.DviewonlyMenu.Checked = 'on';
                    set([app.TridViewUIAxes; app.TridViewUIAxes.Children; app.TridViewUIAxes.Toolbar], 'Visible','on');
                    set([app.LateralViewUIAxes; app.LateralViewUIAxes.Children; app.LateralViewUIAxes.Toolbar], 'Visible','off');
                    set([app.TopViewUIAxes; app.TopViewUIAxes.Children; app.TopViewUIAxes.Toolbar], 'Visible','off');
                    set([app.FrontalViewUIAxes; app.FrontalViewUIAxes.Children; app.FrontalViewUIAxes.Toolbar], 'Visible','off');                   
                otherwise
            end            
        end
        
        % Updates aircraft position in all viewers
        function updateAircraftPosViewers(app,flightNumber)
            % aqui con varios vuelos va a haber que cambiar el tema de los children
            app.TridViewUIAxes.Children(1).Vertices = app.Flights(flightNumber).Aircraft.OutPatchData.vertices;
            app.TridViewUIAxes.Children(1).Faces = app.Flights(flightNumber).Aircraft.OutPatchData.faces;
%             app.FrontalViewUIAxes.Children(1).Vertices = app.Flights(flightNumber).Aircraft.OutPatchData.vertices;
%             app.FrontalViewUIAxes.Children(1).Faces = app.Flights(flightNumber).Aircraft.OutPatchData.faces;
%             app.LateralViewUIAxes.Children(1).Vertices = app.Flights(flightNumber).Aircraft.OutPatchData.vertices;
%             app.LateralViewUIAxes.Children(1).Faces = app.Flights(flightNumber).Aircraft.OutPatchData.faces;
%             app.TopViewUIAxes.Children(1).Vertices = app.Flights(flightNumber).Aircraft.OutPatchData.vertices;
%             app.TopViewUIAxes.Children(1).Faces = app.Flights(flightNumber).Aircraft.OutPatchData.faces;  
            drawnow
        end
    
        % Update device information textbox
        function updateDeviceInfoBox(app)
            if (app.DeviceInfo.DataAvailable)
                textVal = {strcat('Device from: ',app.DeviceInfo.Name,  ' - Connected');...
                    '';...
                    'Available flight data:';...
                    strcat(num2str(app.DeviceInfo.SectorCount4K), ' 4KB sectors', ' (', num2str(app.DeviceInfo.PacketsCount), ' packets of 512 bytes)');...
                    strcat(num2str(double(app.DeviceInfo.PacketsCount)*512), ' bytes');...
                    strcat(num2str(app.DeviceInfo.nSamples), ' sampling periods');...
                    '';...
                    strcat('Flight duration: ', num2str(double(app.DeviceInfo.nSamples)*0.01),' s (', num2str(double(app.DeviceInfo.nSamples)*0.01/60,'%.2f'), ' min)');...
                    strcat('Aprox flight duration: ~ ', num2str(double(app.DeviceInfo.PacketsCount)*512/132*0.01),' s (', num2str(double(app.DeviceInfo.PacketsCount)*512/132*0.01/60,'%.2f'), ' min)')};
            else
                textVal = {strcat('Device from: ', app.DeviceInfo.Name, ' - Connected');...
                    'No flight data available on this device'};                
            end
            app.DeviceInfoTextArea.Value = textVal;
        end
        
        % Clear device information textbox
        function clearDeviceInfoBox(app)
            app.DeviceInfoTextArea.Value = 'Device disconnected';
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadDefaultUserOptions(app);
            initAppInterface(app); 
            app.PlayerTimer = timer("BusyMode","drop","ExecutionMode","fixedRate","Period",0.5,"StartDelay",0,"TasksToExecute",inf,"TimerFcn",@app.playerTimerISR);
            % FSM initialization
            app.StateFSM = 'Initial';
            app.EventFSMQueue = eventQueue(10); % event queue creation. Size 10 elements
            app.nFlights = 0;
            app.Flights = flight;
        end

        % Callback function: AllViewButton, AllViewsMenu, 
        % DViewButton, DviewonlyMenu, FrontalViewButton, 
        % FrontalviewonlyMenu, LateralViewButton, 
        % LateralviewonlyMenu, TopViewButton, TopviewonlyMenu
        function ViewMenuSelected(app, event)
            setViewersMode(app,event.Source.Tag);
        end

        % Clicked callback: LoadDataComputerButton
        function LoadDataComputerButtonPushed(app, event)
            addEvent(app.EventFSMQueue,'LoadComputerData');
            FSM(app);
        end

        % Button pushed function: PlayPlaybackButton
        function PlayPlaybackButtonPushed(app, event)
            addEvent(app.EventFSMQueue,'PlayPause');
            FSM(app);
        end

        % Close request function: F3AAcquisitionSystemRUIFigure
        function F3AAcquisitionSystemRUIFigureCloseRequest(app, event)
            delete(app.FlightsManagerApp);
            delete(app);
        end

        % Callback function: ConnectSwitch, ConnectdeviceMenu
        function ConnectSwitchClicked(app, event)
            addEvent(app.EventFSMQueue,'ConnectButtonPressed');
            FSM(app);            
        end

        % Callback function: CalibrateSensorsButton, 
        % CalibratesensorsMenu
        function CalibrateSensorsButtonClicked(app, event)
            addEvent(app.EventFSMQueue,'CalibrateButtonPressed');
            FSM(app);              
        end

        % Callback function: EraseDevMemoryButton, 
        % ErasedevicememoryMenu
        function EraseDevMemoryButtonClicked(app, event)
            addEvent(app.EventFSMQueue,'EraseButtonPressed');
            FSM(app);              
        end

        % Menu selected function: ConfiguredeviceMenu
        function ConfiguredeviceMenuSelected(app, event)
            
        end

        % Callback function: LoadDataDeviceButton, 
        % LoadfromdevicememoryMenu
        function LoadDataDeviceButtonClicked(app, event)
            addEvent(app.EventFSMQueue,'LoadDeviceDataButtonPressed');
            FSM(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.F3AAcquisitionSystemRUIFigure = uifigure('Visible', 'off');
            app.F3AAcquisitionSystemRUIFigure.Name = 'F3A Acquisition System (R)';
            app.F3AAcquisitionSystemRUIFigure.Icon = 'Aircraft.png';
            app.F3AAcquisitionSystemRUIFigure.CloseRequestFcn = createCallbackFcn(app, @F3AAcquisitionSystemRUIFigureCloseRequest, true);
            app.GeneralMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.GeneralMenu.Text = 'General';
            app.ConnectdeviceMenu = uimenu(app.GeneralMenu);
            app.ConnectdeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectSwitchClicked, true);
            app.ConnectdeviceMenu.Text = 'Connect device';
            app.ConfiguredeviceMenu = uimenu(app.GeneralMenu);
            app.ConfiguredeviceMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguredeviceMenuSelected, true);
            app.ConfiguredeviceMenu.Enable = 'off';
            app.ConfiguredeviceMenu.Text = 'Configure device';
            app.CalibratesensorsMenu = uimenu(app.GeneralMenu);
            app.CalibratesensorsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrateSensorsButtonClicked, true);
            app.CalibratesensorsMenu.Enable = 'off';
            app.CalibratesensorsMenu.Text = 'Calibrate sensors';
            app.ErasedevicememoryMenu = uimenu(app.GeneralMenu);
            app.ErasedevicememoryMenu.MenuSelectedFcn = createCallbackFcn(app, @EraseDevMemoryButtonClicked, true);
            app.ErasedevicememoryMenu.Enable = 'off';
            app.ErasedevicememoryMenu.Text = 'Erase device memory';
            app.LoadflightdataMenu = uimenu(app.GeneralMenu);
            app.LoadflightdataMenu.Text = 'Load flight data';
            app.LoadfromdevicememoryMenu = uimenu(app.LoadflightdataMenu);
            app.LoadfromdevicememoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataDeviceButtonClicked, true);
            app.LoadfromdevicememoryMenu.Enable = 'off';
            app.LoadfromdevicememoryMenu.Text = 'Load from device memory';
            app.LoadfromcomputerfileMenu = uimenu(app.LoadflightdataMenu);
            app.LoadfromcomputerfileMenu.Text = 'Load from computer file';
            app.SaveflightdataMenu = uimenu(app.GeneralMenu);
            app.SaveflightdataMenu.Enable = 'off';
            app.SaveflightdataMenu.Text = 'Save flight data...';
            app.FlightTrackMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.FlightTrackMenu.Text = 'Flight track';
            app.SelectflighttrackMenu = uimenu(app.FlightTrackMenu);
            app.SelectflighttrackMenu.Text = 'Select flight track';
            app.AddnewflighttrackMenu = uimenu(app.FlightTrackMenu);
            app.AddnewflighttrackMenu.Text = 'Add new flight track';
            app.ManageflighttracksMenu = uimenu(app.FlightTrackMenu);
            app.ManageflighttracksMenu.Text = 'Manage flight tracks';
            app.ManeuverscheduleMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.ManeuverscheduleMenu.Text = 'Maneuver schedule';
            app.AddnewscheduleMenu = uimenu(app.ManeuverscheduleMenu);
            app.AddnewscheduleMenu.Text = 'Add new schedule';
            app.LoadscheduleMenu = uimenu(app.ManeuverscheduleMenu);
            app.LoadscheduleMenu.Text = 'Load schedule';
            app.ManageschedulesMenu = uimenu(app.ManeuverscheduleMenu);
            app.ManageschedulesMenu.Text = 'Manage schedules';
            app.AircraftmodelMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.AircraftmodelMenu.Text = 'Aircraft model';
            app.AddnewmodelMenu = uimenu(app.AircraftmodelMenu);
            app.AddnewmodelMenu.Text = 'Add new model';
            app.ManagemodelsMenu = uimenu(app.AircraftmodelMenu);
            app.ManagemodelsMenu.Text = 'Manage models';
            app.FlightViewerMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.FlightViewerMenu.Text = 'Flight viewer';
            app.ViewsMenu = uimenu(app.FlightViewerMenu);
            app.ViewsMenu.Text = 'Views';
            app.AllViewsMenu = uimenu(app.ViewsMenu);
            app.AllViewsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.AllViewsMenu.Checked = 'on';
            app.AllViewsMenu.Text = '4 views';
            app.AllViewsMenu.Tag = 'AllViews';
            app.FrontalviewonlyMenu = uimenu(app.ViewsMenu);
            app.FrontalviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.FrontalviewonlyMenu.Text = 'Frontal view only';
            app.FrontalviewonlyMenu.Tag = 'FrontalView';
            app.LateralviewonlyMenu = uimenu(app.ViewsMenu);
            app.LateralviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.LateralviewonlyMenu.Text = 'Lateral view only';
            app.LateralviewonlyMenu.Tag = 'LateralView';
            app.TopviewonlyMenu = uimenu(app.ViewsMenu);
            app.TopviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.TopviewonlyMenu.Text = 'Top view only';
            app.TopviewonlyMenu.Tag = 'TopView';
            app.DviewonlyMenu = uimenu(app.ViewsMenu);
            app.DviewonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.DviewonlyMenu.Text = '3D view only';
            app.DviewonlyMenu.Tag = '3DView';
            app.MainaircraftytrajectoryMenu = uimenu(app.FlightViewerMenu);
            app.MainaircraftytrajectoryMenu.Text = 'Main aircrafty trajectory';
            app.NotrajectorydisplayMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.NotrajectorydisplayMenu.Text = 'No trajectory display';
            app.ShowaircrafttrailMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.ShowaircrafttrailMenu.Checked = 'on';
            app.ShowaircrafttrailMenu.Text = 'Show aircraft trail';
            app.ShowpasttrajectoryMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.ShowpasttrajectoryMenu.Text = 'Show past trajectory';
            app.ShowfulltrajectoryMenu = uimenu(app.MainaircraftytrajectoryMenu);
            app.ShowfulltrajectoryMenu.Text = 'Show full trajectory';
            app.ManageloadedflightsMenu = uimenu(app.FlightViewerMenu);
            app.ManageloadedflightsMenu.Enable = 'off';
            app.ManageloadedflightsMenu.Text = 'Manage loaded flights';
            app.HelpMenu = uimenu(app.F3AAcquisitionSystemRUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.F3AAcquisitionSystemRUIFigure);
            app.ConnectSwitch = uitoggletool(app.Toolbar);
            app.ConnectSwitch.ClickedCallback = createCallbackFcn(app, @ConnectSwitchClicked, true);
            app.ConnectSwitch.Icon = 'Connect.png';
            app.CalibrateSensorsButton = uipushtool(app.Toolbar);
            app.CalibrateSensorsButton.Tooltip = {'Calibrate sensors'};
            app.CalibrateSensorsButton.ClickedCallback = createCallbackFcn(app, @CalibrateSensorsButtonClicked, true);
            app.CalibrateSensorsButton.Enable = 'off';
            app.CalibrateSensorsButton.Icon = 'Calibrate.png';
            app.EraseDevMemoryButton = uipushtool(app.Toolbar);
            app.EraseDevMemoryButton.Tooltip = {'Erase device memory'};
            app.EraseDevMemoryButton.ClickedCallback = createCallbackFcn(app, @EraseDevMemoryButtonClicked, true);
            app.EraseDevMemoryButton.Enable = 'off';
            app.EraseDevMemoryButton.Icon = 'Bin.png';
            app.LoadDataDeviceButton = uipushtool(app.Toolbar);
            app.LoadDataDeviceButton.Tooltip = {'Load last flight data from device memory'};
            app.LoadDataDeviceButton.ClickedCallback = createCallbackFcn(app, @LoadDataDeviceButtonClicked, true);
            app.LoadDataDeviceButton.Enable = 'off';
            app.LoadDataDeviceButton.Icon = 'Load data from device.png';
            app.LoadDataDeviceButton.Separator = 'on';
            app.LoadDataComputerButton = uipushtool(app.Toolbar);
            app.LoadDataComputerButton.Tooltip = {'Load flight data from computer file'};
            app.LoadDataComputerButton.ClickedCallback = createCallbackFcn(app, @LoadDataComputerButtonPushed, true);
            app.LoadDataComputerButton.Icon = 'Load data from file.png';
            app.LoadManeuverSchButton = uipushtool(app.Toolbar);
            app.LoadManeuverSchButton.Tooltip = {'Load maneuver schedule'};
            app.LoadManeuverSchButton.Enable = 'off';
            app.LoadManeuverSchButton.Icon = 'Maneuver.png';
            app.SaveFlightButton = uipushtool(app.Toolbar);
            app.SaveFlightButton.Tooltip = {'Save flight recording to computer'};
            app.SaveFlightButton.Enable = 'off';
            app.SaveFlightButton.Icon = 'Save.png';
            app.ManageLoadedFlightsButton = uipushtool(app.Toolbar);
            app.ManageLoadedFlightsButton.Tooltip = {'Manage loaded flights'};
            app.ManageLoadedFlightsButton.Enable = 'off';
            app.ManageLoadedFlightsButton.Icon = 'FlightDataG.png';
            app.ManageLoadedFlightsButton.Separator = 'on';
            app.FrontalViewButton = uipushtool(app.Toolbar);
            app.FrontalViewButton.Tag = 'FrontalView';
            app.FrontalViewButton.Tooltip = {'Front view only'};
            app.FrontalViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.FrontalViewButton.Icon = 'FrontView.png';
            app.FrontalViewButton.Separator = 'on';
            app.LateralViewButton = uipushtool(app.Toolbar);
            app.LateralViewButton.Tag = 'LateralView';
            app.LateralViewButton.Tooltip = {'Lateral view only'};
            app.LateralViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.LateralViewButton.Icon = 'LateralView.png';
            app.TopViewButton = uipushtool(app.Toolbar);
            app.TopViewButton.Tag = 'TopView';
            app.TopViewButton.Tooltip = {'Top view only'};
            app.TopViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.TopViewButton.Icon = 'TopView.png';
            app.DViewButton = uipushtool(app.Toolbar);
            app.DViewButton.Tag = '3DView';
            app.DViewButton.Tooltip = {'3D view only'};
            app.DViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.DViewButton.Icon = '3DView.png';
            app.AllViewButton = uipushtool(app.Toolbar);
            app.AllViewButton.Tag = 'AllViews';
            app.AllViewButton.Tooltip = {'All views'};
            app.AllViewButton.ClickedCallback = createCallbackFcn(app, @ViewMenuSelected, true);
            app.AllViewButton.Icon = 'AllViews.png';
            app.GlobalGridLayout = uigridlayout(app.F3AAcquisitionSystemRUIFigure);
            app.GridLayout2 = uigridlayout(app.GlobalGridLayout);
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.DevicePropertiesPanel = uipanel(app.GridLayout8);
            app.DevicePropertiesPanel.Title = 'Device information';
            app.GridLayout9 = uigridlayout(app.DevicePropertiesPanel);
            app.Label_5 = uilabel(app.GridLayout9);
            app.Label_5.Text = '';
            app.DeviceInfoTextArea = uitextarea(app.GridLayout9);
            app.DeviceInfoTextArea.Editable = 'off';
            app.DatagraphsPanel = uipanel(app.GridLayout8);
            app.DatagraphsPanel.Title = 'Data graphs';
            app.GridLayout10 = uigridlayout(app.DatagraphsPanel);
            app.ListBox = uilistbox(app.GridLayout10);
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.Label_6 = uilabel(app.GridLayout10);
            app.Label_6.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout10);
            app.Graph1Tab = uitab(app.TabGroup);
            app.Graph1Tab.Title = 'Graph1';
            app.Graph2Tab = uitab(app.TabGroup);
            app.Graph2Tab.Title = 'Graph2';
            app.TrajectoryPanel = uipanel(app.GridLayout2);
            app.TrajectoryPanel.Title = 'Flight viewer';
            app.ViewersGridLayout = uigridlayout(app.TrajectoryPanel);
            app.FrontalViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.FrontalViewUIAxes, 'Frontal')
            xlabel(app.FrontalViewUIAxes, 'X')
            ylabel(app.FrontalViewUIAxes, 'Y')
            zlabel(app.FrontalViewUIAxes, 'Z')
            app.LateralViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.LateralViewUIAxes, 'Lateral')
            xlabel(app.LateralViewUIAxes, 'X')
            ylabel(app.LateralViewUIAxes, 'Y')
            zlabel(app.LateralViewUIAxes, 'Z')
            app.TopViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.TopViewUIAxes, 'Top')
            xlabel(app.TopViewUIAxes, 'X')
            ylabel(app.TopViewUIAxes, 'Y')
            zlabel(app.TopViewUIAxes, 'Z')
            app.TridViewUIAxes = uiaxes(app.ViewersGridLayout);
            title(app.TridViewUIAxes, '3D')
            xlabel(app.TridViewUIAxes, 'X')
            ylabel(app.TridViewUIAxes, 'Y')
            zlabel(app.TridViewUIAxes, 'Z')
            app.PlaybackControlsPanel = uipanel(app.GlobalGridLayout);
            app.GridLayout7 = uigridlayout(app.PlaybackControlsPanel);
            app.PrevFrameButton = uibutton(app.GridLayout7, 'push');
            app.PrevFrameButton.Icon = 'Previous.png';
            app.PrevFrameButton.Tooltip = {'Previous frame'};
            app.PrevFrameButton.Text = '';
            app.PlayPlaybackButton = uibutton(app.GridLayout7, 'push');
            app.PlayPlaybackButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPlaybackButtonPushed, true);
            app.PlayPlaybackButton.Icon = 'Play.png';
            app.PlayPlaybackButton.Text = '';
            app.StopPlaybackButton = uibutton(app.GridLayout7, 'push');
            app.StopPlaybackButton.Icon = 'Stop.png';
            app.StopPlaybackButton.Tooltip = {''};
            app.StopPlaybackButton.Text = '';
            app.NextFrameButton = uibutton(app.GridLayout7, 'push');
            app.NextFrameButton.Icon = 'Next.png';
            app.NextFrameButton.Tooltip = {'Next frame'};
            app.NextFrameButton.Text = '';
            app.Panel = uipanel(app.GridLayout7);
            app.PlaybackDropDown = uidropdown(app.Panel);
            app.PlaybackDropDown.Items = {'0.25x', '0.5x', '0.75x', '1x', '1.25x', '1.5x', '1.75x', '2x'};
            app.PlaybackDropDown.ItemsData = {'0.25', '0.5', '0.75', '1', '1.25', '1.5', '1.75', '2'};
            app.PlaybackDropDown.Value = '1';
            app.PlaybackDropDownLabel = uilabel(app.Panel);
            app.PlaybackDropDownLabel.Text = 'Playback';
            app.FlightInstantEditField = uieditfield(app.GridLayout7, 'numeric');
            app.PlaybackSlider = uislider(app.GridLayout7);
            app.RepeatButton = uibutton(app.GridLayout7, 'state');
            app.RepeatButton.Tooltip = {'Repeat in loop'};
            app.RepeatButton.Icon = 'Repeat.png';
            app.RepeatButton.Text = '';
            % Show the figure after all components are created
            app.F3AAcquisitionSystemRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.F3AAcquisitionSystemRUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.F3AAcquisitionSystemRUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.F3AAcquisitionSystemRUIFigure)
        end
    end
end